classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button2            matlab.ui.control.Button
        Theta6Slider       matlab.ui.control.Slider
        Theta6SliderLabel  matlab.ui.control.Label
        Theta5Slider       matlab.ui.control.Slider
        Theta5SliderLabel  matlab.ui.control.Label
        Theta4Slider       matlab.ui.control.Slider
        Theta4SliderLabel  matlab.ui.control.Label
        Theta3Slider       matlab.ui.control.Slider
        Theta3SliderLabel  matlab.ui.control.Label
        Theta2Slider       matlab.ui.control.Slider
        Theta2SliderLabel  matlab.ui.control.Label
        Theta1Slider       matlab.ui.control.Slider
        Theta1SliderLabel  matlab.ui.control.Label
        LoadRobotButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        modelRobot % Where I will put the loaded puma
        qz = [0     0     0     0     0     0];
        qrPuma = [0	pi/2  -pi/2	0	0	0];

        th_1;
        th_2;
        th_3;
        th_4;
        th_5;
        th_6;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axes(app.UIAxes);
            cla(app.UIAxes)
            ax = gca;

            L1 = Link('d',0.275,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            L2 = Link('d',0,'a',0.7,'alpha',-pi,'offset',-pi/2,'qlim',[deg2rad(-90),deg2rad(90)]);
            L3 = Link('d',0,'a',0.5,'alpha',pi,'offset',0,'qlim',[deg2rad(-170),deg2rad(170)]);
            L4 = Link('d',0.162,'a',0,'alpha',-pi/2,'offset',-pi/2,'qlim',[deg2rad(-360),deg2rad(360)]);
            L5 = Link('d',0.170,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            L6 = Link('d',0,'a',0,'alpha',0,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            app.modelRobot = SerialLink([L1 L2 L3 L4 L5 L6],'name','MOTOMAN HC10DTP');
            plot(app.modelRobot,app.qrPuma)
            drawnow();
            while 0 < length(ax.Children)
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))

            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];


        end

        % Button pushed function: LoadRobotButton
        function LoadRobotButtonPushed(app, event)
            qrnew = [app.th_1 app.th_2 app.th_3 app.th_4 app.th_5 app.th_6];
            qmatrix = jtraj(app.qrPuma,qrnew,12);
            for robotStepIndex = 1:size(qmatrix,1)

                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end

                % Plot it by moving the handle of the robot
                animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                drawnow
            end

        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)

        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            value = app.Theta1Slider.Value;
            app.th_1 = value;
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            value = app.Theta2Slider.Value;
            app.th_2 = value;
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            value = app.Theta3Slider.Value;
            app.th_3 = value;
        end

        % Value changed function: Theta4Slider
        function Theta4SliderValueChanged(app, event)
            value = app.Theta4Slider.Value;
            app.th_4 = value;
        end

        % Value changed function: Theta5Slider
        function Theta5SliderValueChanged(app, event)
            value = app.Theta5Slider.Value;
            app.th_5 = value;
        end

        % Value changed function: Theta6Slider
        function Theta6SliderValueChanged(app, event)
            value = app.Theta6Slider.Value;
            app.th_6 = value;
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
           q= [app.th_1, app.th_2,app.th_3, app.th_4, app.th_5, app.th_6];

           app.modelRobot.fkine(q)
           drawnow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadRobotButton = uibutton(app.UIFigure, 'push');
            app.LoadRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotButton.Text = 'Load Robot';
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta4SliderLabel = uilabel(app.UIFigure);
            app.Theta4SliderLabel.Text = 'Theta 4';
            app.Theta4Slider = uislider(app.UIFigure);
            app.Theta4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta4SliderValueChanged, true);
            app.Theta5SliderLabel = uilabel(app.UIFigure);
            app.Theta5SliderLabel.Text = 'Theta 5';
            app.Theta5Slider = uislider(app.UIFigure);
            app.Theta5Slider.ValueChangedFcn = createCallbackFcn(app, @Theta5SliderValueChanged, true);
            app.Theta6SliderLabel = uilabel(app.UIFigure);
            app.Theta6SliderLabel.Text = 'Theta 6';
            app.Theta6Slider = uislider(app.UIFigure);
            app.Theta6Slider.ValueChangedFcn = createCallbackFcn(app, @Theta6SliderValueChanged, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end